classdef callPickerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        timeslidersSliderLabel          matlab.ui.control.Label
        timesSlider                     matlab.ui.control.Slider
        LowFreqLimHzEditFieldLabel      matlab.ui.control.Label
        LowFreqEditField                matlab.ui.control.NumericEditField
        UpperFreqHzLabel                matlab.ui.control.Label
        UpperFreqEditField              matlab.ui.control.NumericEditField
        DurationsEditFieldLabel         matlab.ui.control.Label
        DurationsEditField              matlab.ui.control.NumericEditField
        ColormapButtonGroup             matlab.ui.container.ButtonGroup
        jetButton                       matlab.ui.control.ToggleButton
        parulaButton                    matlab.ui.control.ToggleButton
        springButton                    matlab.ui.control.ToggleButton
        UpdBEditFieldLabel              matlab.ui.control.Label
        UpdBEditField                   matlab.ui.control.NumericEditField
        LowdBEditField                  matlab.ui.control.NumericEditField
        NFFTEditFieldLabel              matlab.ui.control.Label
        NFFTEditField                   matlab.ui.control.NumericEditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        OverlapEditField                matlab.ui.control.NumericEditField
        YLimitQuantileLabel             matlab.ui.control.Label
        YLimEditField                   matlab.ui.control.NumericEditField
        LoadButton                      matlab.ui.control.Button
        WindowLabel                     matlab.ui.control.Label
        WindowEditField                 matlab.ui.control.NumericEditField
        CommentEditFieldLabel           matlab.ui.control.Label
        CommentEditField                matlab.ui.control.EditField
        commentLabel                    matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        SaveButton                      matlab.ui.control.Button
        pathoutEditFieldLabel           matlab.ui.control.Label
        pathoutEditField                matlab.ui.control.EditField
        BandpassfilterLabel             matlab.ui.control.Label
        defaultlowpass3000HzLabel       matlab.ui.control.Label
        PressanykeytosaveeachcallLabel  matlab.ui.control.Label
        RedcallNOTsavedGreencallsavedLabel  matlab.ui.control.Label
        DELETEButton                    matlab.ui.control.Button
        UpperFreqHzLabel_2              matlab.ui.control.Label
        UpperFreqEditField_bp           matlab.ui.control.NumericEditField
        LowFreqHzLabel                  matlab.ui.control.Label
        LowFreqEditField_bp             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
           file
           path 
           currFile
           F
           T
           Pxx
           time 
           locDown
           locUp
           temp
           yfilt
           calltable
           callNum
           callbox
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
           [app.file,app.path] = uigetfile(); % allows user to select file 
            app.currFile = load(strcat(app.path,'/',app.file));
            [~,app.F,app.T,app.Pxx]=spectrogram(app.currFile.y, app.WindowEditField.Value,...
                floor(app.WindowEditField.Value*(app.OverlapEditField.Value/100)),...
                app.NFFTEditField.Value,app.currFile.fs); % get these from default GIU values 
            app.time=(0:1:length(app.currFile.y)-1)*(1/app.currFile.fs);
            app.yfilt=lowpass(app.currFile.y,3000,app.currFile.fs,'Steepness',0.85); %filters y so it is between the freq range of the default spectrogram plot
            imagesc(app.UIAxes,app.T,app.F,10*log10(app.Pxx)); axis(app.UIAxes,'xy'); 
            ylim(app.UIAxes,[app.LowFreqEditField.Value, app.UpperFreqEditField.Value]); 
            colormap(app.UIAxes,'jet'); 
            xlim(app.UIAxes,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
            caxis(app.UIAxes,[app.LowdBEditField.Value,app.UpdBEditField.Value ]);  hold on;
            plot(app.UIAxes_2,app.time,app.yfilt); 
            xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value]);   % set x lim for time series 
             hold(app.UIAxes,'on'); hold(app.UIAxes_2,'on')
             zoom(app.UIAxes,'off'); hold(app.UIAxes_2,'off')
             
             app.callNum = 1;
             
              app.calltable = table('Size',[1,8],'VariableTypes',{'string','string','double','double','double','double','double','string'},...
                 'VariableNames',{'site','deployment','chunkStartTime','callStartTime','callEndTime','callLowerFreq','callUpperFreq','comment'}); 
     
        end

        % Value changed function: timesSlider
        function timesSliderValueChanged(app, event)
            value = app.timesSlider.Value;
            xlim(app.UIAxes,[value,value+app.DurationsEditField.Value])
            xlim(app.UIAxes_2,[value,value+app.DurationsEditField.Value])
        end

        % Value changed function: LowFreqEditField
        function LowFreqEditFieldValueChanged(app, event)
            value = app.LowFreqEditField.Value;
            ylim(app.UIAxes,[value app.UpperFreqEditField.Value]); 
        end

        % Value changed function: UpperFreqEditField
        function UpperFreqEditFieldValueChanged(app, event)
            value = app.UpperFreqEditField.Value;
            ylim(app.UIAxes,[app.LowFreqEditField.Value, value]);
        end

        % Value changed function: NFFTEditField
        function NFFTEditFieldValueChanged(app, event)
            value = app.NFFTEditField.Value;
            hold(app.UIAxes,'off'); hold(app.UIAxes_2,'off')
            [~,app.F,app.T,app.Pxx]=spectrogram(app.currFile.y, app.WindowEditField.Value,...
                floor(app.WindowEditField.Value*(app.OverlapEditField.Value/100)),...
                value,app.currFile.fs); % get these from default GIU values 
            imagesc(app.UIAxes,app.T,app.F,10*log10(app.Pxx)); axis(app.UIAxes,'xy'); 
            ylim(app.UIAxes,[app.LowFreqEditField.Value, app.UpperFreqEditField.Value]); 
            colormap(app.UIAxes,'jet'); 
            xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
            caxis(app.UIAxes,[app.LowdBEditField.Value,app.UpdBEditField.Value ]);  hold on; 
            plot(app.UIAxes_2,app.time,app.yfilt); 
            xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
             hold(app.UIAxes,'on'); hold(app.UIAxes_2,'on')
             zoom(app.UIAxes,'off'); hold(app.UIAxes_2,'off')
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
             value = app.WindowEditField.Value;
             hold(app.UIAxes,'off'); hold(app.UIAxes_2,'off')
                 [~,app.F,app.T,app.Pxx]=spectrogram(app.currFile.y, value,...
                floor(app.WindowEditField.Value*(app.OverlapEditField.Value/100)),...
                app.NFFTEditField.Value,app.currFile.fs); % get these from default GIU values
            imagesc(app.UIAxes,app.T,app.F,10*log10(app.Pxx)); axis(app.UIAxes,'xy'); 
            ylim(app.UIAxes,[app.LowFreqEditField.Value, app.UpperFreqEditField.Value]); 
            colormap(app.UIAxes,'jet'); 
            xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
            caxis(app.UIAxes,[app.LowdBEditField.Value,app.UpdBEditField.Value ]);  hold on; 
            plot(app.UIAxes_2,app.time,app.yfilt); 
            xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
             hold(app.UIAxes,'on'); hold(app.UIAxes_2,'on')
             zoom(app.UIAxes,'off'); hold(app.UIAxes_2,'off')
        end

        % Value changed function: OverlapEditField
        function OverlapEditFieldValueChanged(app, event)
            value = app.OverlapEditField.Value;
             hold(app.UIAxes,'off'); hold(app.UIAxes_2,'off')
                [~,app.F,app.T,app.Pxx]=spectrogram(app.currFile.y, app.WindowEditField.Value,...
                floor(app.WindowEditField.Value*(value/100)),...
                app.NFFTEditField.Value,app.currFile.fs); % get these from default GIU values 
            app.yfilt=bandpass(app.currFile.y,[app.LowFreqEditField.Value,app.UpperFreqEditField.Value],app.currFile.fs,'Steepness',0.85);
            imagesc(app.UIAxes,app.T,app.F,10*log10(app.Pxx)); axis(app.UIAxes,'xy'); 
            ylim(app.UIAxes,[app.LowFreqEditField.Value, app.UpperFreqEditField.Value]); 
            colormap(app.UIAxes,'jet'); 
            xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
            caxis(app.UIAxes,[app.LowdBEditField.Value,app.UpdBEditField.Value]); 
            plot(app.UIAxes_2,app.time,app.yfilt); 
            xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
             hold(app.UIAxes,'on'); hold(app.UIAxes_2,'on')
             zoom(app.UIAxes,'off'); hold(app.UIAxes_2,'off')
        end

        % Value changed function: UpdBEditField
        function UpdBEditFieldValueChanged(app, event)
            value = app.UpdBEditField.Value;
            caxis(app.UIAxes,[app.LowdBEditField.Value,value]); 
        end

        % Value changed function: LowdBEditField
        function LowdBEditFieldValueChanged(app, event)
            value = app.LowdBEditField.Value;
            caxis(app.UIAxes,[value,app.UpdBEditField.Value]); 
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColormapButtonGroup.SelectedObject;
             colormap(app.UIAxes,selectedButton.Text);
        end

        % Value changed function: YLimEditField
        function YLimEditFieldValueChanged(app, event)
            value = app.YLimEditField.Value;
            hold(app.UIAxes_2,'off')
            plot(app.UIAxes_2,app.time,app.yfilt); 
            A=floor(app.currFile.fs*app.timesSlider.Value)+1; 
            B=floor(app.currFile.fs*(app.timesSlider.Value+app.DurationsEditField.Value));
            mlim=max(abs(app.currFile.y(A:B)));
            xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value]);
            ylim(app.UIAxes_2,[-1*mlim*value, 1*mlim*value])
        end

        % Value changed function: DurationsEditField
        function DurationsEditFieldValueChanged(app, event)
            value = app.DurationsEditField.Value;
             xlim(app.UIAxes,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
             xlim(app.UIAxes_2,[app.timesSlider.Value, app.timesSlider.Value+app.DurationsEditField.Value])
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            app.locDown = [app.temp(1,1) app.temp(1,2)]; % Gets the (x,y) coordinate
            sprintf('Start Time: %5.3f upper F: %5.3f',app.locDown)
            app.Lamp.Color = 'red';
            
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
                   app.temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            app.locUp = [app.temp(1,1) app.temp(1,2)]; % Gets the (x,y) coordinate
            sprintf('Duration: %5.3f Delta-F%5.3f',app.locUp-app.locDown) 
             hold(app.UIAxes,'on')
            app.callbox = plot(app.UIAxes,[app.locDown(1),app.locUp(1),app.locUp(1),app.locDown(1),app.locDown(1)],[app.locDown(2),app.locDown(2),app.locUp(2),app.locUp(2),app.locDown(2)],'w','Linewidth',3)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
       app.calltable(app.callNum,1)={app.currFile.site}; 
       app.calltable(app.callNum,2)={app.currFile.dep}; 
       app.calltable(app.callNum,3)=num2cell(app.currFile.fstart); 
       app.calltable(app.callNum,4)=num2cell(app.locDown(1)); 
       app.calltable(app.callNum,5)=num2cell(app.locUp(1));
       app.calltable(app.callNum,6)=num2cell(app.locUp(2)); 
       app.calltable(app.callNum,7)=num2cell(app.locDown(2));  
       app.calltable(app.callNum,8)={app.CommentEditField.Value}; %insert text from comment box
      
            %app.h= plot(app.UIAxes,[app.locDown(1),app.locUp(1),app.locUp(1),app.locDown(1),app.locDown(1)],[app.locDown(2),app.locDown(2),app.locUp(2),app.locUp(2),app.locDown(2)],'k','Linewidth',3)

            app.callbox.Color = 'k';
            app.callNum = app.callNum +1;
            
            app.Lamp.Color = 'green';
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.calltable
           fn=strcat('calltable_',app.currFile.site,'_',app.currFile.dep,'_',datestr(app.currFile.fstart,'yyyymmdd_HHMM')); 
           fn=strrep(fn,'.','_'); 
           writetable(app.calltable,strcat(app.pathoutEditField.Value,fn)); 
        end

        % Value changed function: pathoutEditField
        function pathoutEditFieldValueChanged(app, event)
            value = app.pathoutEditField.Value;
            
        end

        % Value changed function: CommentEditField
        function CommentEditFieldValueChanged(app, event)
            value = app.CommentEditField.Value;
            
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
        app.calltable.site(app.callNum - 1) = NaN;
        app.calltable.deployment(app.callNum - 1) = NaN;
        app.calltable.chunkStartTime(app.callNum - 1) = NaN;
        app.calltable.callStartTime(app.callNum - 1) = NaN;
        app.calltable.callEndTime(app.callNum - 1) = NaN;
        app.calltable.callLowerFreq(app.callNum - 1) = NaN;
        app.calltable.callUpperFreq(app.callNum - 1) = NaN;
        app.calltable.comment(app.callNum - 1) = NaN;
        
        app.callbox.Color = 'w';
        %plot(app.UIAxes,[app.locDown(1),app.locUp(1),app.locUp(1),app.locDown(1),app.locDown(1)],[app.locDown(2),app.locDown(2),app.locUp(2),app.locUp(2),app.locDown(2)],'w','Linewidth',3)
        
        end

        % Value changed function: UpperFreqEditField_bp
        function UpperFreqEditField_bpValueChanged(app, event)
            value = app.UpperFreqEditField_bp.Value;
            if app.LowFreqEditField_bp.Value >= 50;
            app.yfilt=bandpass(app.currFile.y,[app.LowFreqEditField_bp.Value,value],app.currFile.fs,'Steepness',0.85);
            plot(app.UIAxes_2,app.time,app.yfilt);
            else
            app.yfilt=lowpass(app.currFile.y,value,app.currFile.fs,'Steepness',0.85);
            plot(app.UIAxes_2,app.time,app.yfilt);
            end
        end

        % Value changed function: LowFreqEditField_bp
        function LowFreqEditField_bpValueChanged(app, event)
            value = app.LowFreqEditField_bp.Value;
            if value > 50;
            app.yfilt=bandpass(app.currFile.y,[value,app.UpperFreqEditField_bp.Value],app.currFile.fs,'Steepness',0.85);
            plot(app.UIAxes_2,app.time,app.yfilt);
            else
            app.yfilt=lowpass(app.currFile.y,app.UpperFreqEditField_bp.Value,app.currFile.fs,'Steepness',0.85);
            plot(app.UIAxes_2,app.time,app.yfilt);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SPECTROGRAM')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Freq (Hz)')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'TIME DOMAIN')
            xlabel(app.UIAxes_2, 'Time (sec)')
            ylabel(app.UIAxes_2, 'Amplitude (uPa)')
            app.timeslidersSliderLabel = uilabel(app.UIFigure);
            app.timeslidersSliderLabel.Text = 'time slider (s)';
            app.timesSlider = uislider(app.UIFigure);
            app.timesSlider.Limits = [0 30];
            app.timesSlider.ValueChangedFcn = createCallbackFcn(app, @timesSliderValueChanged, true);
            app.LowFreqLimHzEditFieldLabel = uilabel(app.UIFigure);
            app.LowFreqLimHzEditFieldLabel.Text = 'Low Freq (Hz)';
            app.LowFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowFreqEditField.ValueChangedFcn = createCallbackFcn(app, @LowFreqEditFieldValueChanged, true);
            app.UpperFreqHzLabel = uilabel(app.UIFigure);
            app.UpperFreqHzLabel.Text = 'Upper Freq (Hz)';
            app.UpperFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperFreqEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFreqEditFieldValueChanged, true);
            app.UpperFreqEditField.Value = 3000;
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsEditFieldValueChanged, true);
            app.DurationsEditField.Value = 1;
            app.ColormapButtonGroup = uibuttongroup(app.UIFigure);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged, true);
            app.ColormapButtonGroup.Title = '        Colormap';
            app.jetButton = uitogglebutton(app.ColormapButtonGroup);
            app.jetButton.Text = 'jet';
            app.jetButton.Value = true;
            app.parulaButton = uitogglebutton(app.ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.springButton = uitogglebutton(app.ColormapButtonGroup);
            app.springButton.Text = 'spring';
            app.UpdBEditFieldLabel = uilabel(app.UIFigure);
            app.UpdBEditFieldLabel.Text = 'Colormap Limits (dB)';
            app.UpdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdBEditField.ValueChangedFcn = createCallbackFcn(app, @UpdBEditFieldValueChanged, true);
            app.UpdBEditField.Value = 90;
            app.LowdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowdBEditField.ValueChangedFcn = createCallbackFcn(app, @LowdBEditFieldValueChanged, true);
            app.LowdBEditField.Value = 40;
            app.NFFTEditFieldLabel = uilabel(app.UIFigure);
            app.NFFTEditFieldLabel.Text = 'NFFT';
            app.NFFTEditField = uieditfield(app.UIFigure, 'numeric');
            app.NFFTEditField.ValueDisplayFormat = '%11.0f';
            app.NFFTEditField.ValueChangedFcn = createCallbackFcn(app, @NFFTEditFieldValueChanged, true);
            app.NFFTEditField.Value = 2048;
            app.OverlapEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapEditFieldValueChanged, true);
            app.OverlapEditField.Value = 90;
            app.YLimitQuantileLabel = uilabel(app.UIFigure);
            app.YLimitQuantileLabel.Text = 'Y Limit Quantile';
            app.YLimEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLimEditField.ValueChangedFcn = createCallbackFcn(app, @YLimEditFieldValueChanged, true);
            app.YLimEditField.Value = 0.99;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.WindowLabel = uilabel(app.UIFigure);
            app.WindowLabel.Text = 'Window';
            app.WindowEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Value = 1024;
            app.CommentEditFieldLabel = uilabel(app.UIFigure);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.UIFigure, 'text');
            app.CommentEditField.ValueChangedFcn = createCallbackFcn(app, @CommentEditFieldValueChanged, true);
            app.commentLabel = uilabel(app.UIFigure);
            app.commentLabel.Text = 'Ex: good call!, wtf is this?';
            app.Lamp = uilamp(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.pathoutEditFieldLabel = uilabel(app.UIFigure);
            app.pathoutEditFieldLabel.Text = 'path out:';
            app.pathoutEditField = uieditfield(app.UIFigure, 'text');
            app.pathoutEditField.ValueChangedFcn = createCallbackFcn(app, @pathoutEditFieldValueChanged, true);
            app.pathoutEditField.Value = '/Users/sbrown/Documents/MATLAB/calltables/';
            app.BandpassfilterLabel = uilabel(app.UIFigure);
            app.BandpassfilterLabel.Text = 'Bandpass filter';
            app.defaultlowpass3000HzLabel = uilabel(app.UIFigure);
            app.defaultlowpass3000HzLabel.Text = '*default: lowpass 3000 Hz';
            app.PressanykeytosaveeachcallLabel = uilabel(app.UIFigure);
            app.PressanykeytosaveeachcallLabel.Text = 'Press any key to save each call.';
            app.RedcallNOTsavedGreencallsavedLabel = uilabel(app.UIFigure);
            app.RedcallNOTsavedGreencallsavedLabel.Text = 'Red: call NOT saved, Green: call saved';
            app.DELETEButton = uibutton(app.UIFigure, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Text = 'DELETE';
            app.UpperFreqHzLabel_2 = uilabel(app.UIFigure);
            app.UpperFreqHzLabel_2.Text = 'Upper Freq (Hz)';
            app.UpperFreqEditField_bp = uieditfield(app.UIFigure, 'numeric');
            app.UpperFreqEditField_bp.ValueChangedFcn = createCallbackFcn(app, @UpperFreqEditField_bpValueChanged, true);
            app.UpperFreqEditField_bp.Value = 3000;
            app.LowFreqHzLabel = uilabel(app.UIFigure);
            app.LowFreqHzLabel.Text = 'Low Freq (Hz)';
            app.LowFreqEditField_bp = uieditfield(app.UIFigure, 'numeric');
            app.LowFreqEditField_bp.ValueChangedFcn = createCallbackFcn(app, @LowFreqEditField_bpValueChanged, true);
            app.LowFreqEditField_bp.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = callPickerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end