classdef chorusPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadDataButton              matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        lowerEditFieldLabel         matlab.ui.control.Label
        lowerEditField              matlab.ui.control.NumericEditField
        upperEditFieldLabel         matlab.ui.control.Label
        upperEditField              matlab.ui.control.NumericEditField
        YLimitsLabel                matlab.ui.control.Label
        UpdBEditFieldLabel          matlab.ui.control.Label
        UpdBEditField               matlab.ui.control.NumericEditField
        LowdBEditField              matlab.ui.control.NumericEditField
        SaveButton                  matlab.ui.control.Button
        pathoutEditFieldLabel       matlab.ui.control.Label
        pathoutEditField            matlab.ui.control.EditField
        CommentEditField_2Label     matlab.ui.control.Label
        CommentEditField            matlab.ui.control.EditField
        BackButton                  matlab.ui.control.Button
        ForwardButton               matlab.ui.control.Button
        NumberofDaysEditFieldLabel  matlab.ui.control.Label
        NumberofDaysEditField       matlab.ui.control.NumericEditField
        NotematfileMUSTincludefstartfpoavgdepandsitevariablesLabel  matlab.ui.control.Label
        EntertextBEFOREselectingchorusLabel  matlab.ui.control.Label
        lowerEditFieldLabel_2       matlab.ui.control.Label
        upperEditFieldLabel_2       matlab.ui.control.Label
        ReadmeTextAreaLabel         matlab.ui.control.Label
        ReadmeTextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        file
        path
        currFile
        startDay
        endDay
        chorusNum
        temp
        locDown
        locUp
        callbox
        chorustable
        xl
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [app.file,app.path] = uigetfile(); % allows user to select file 
            app.currFile = load(strcat(app.path,'/',app.file)); %this will load a mat file with all variables needed to plot spectrogram
            app.startDay = floor(app.currFile.fstart(1));
            app.endDay = ceil(app.currFile.fstart(end));
            
            %plot spectrogram
            imagesc(app.UIAxes,app.currFile.fstart,app.currFile.f,10*log10(app.currFile.poavg)); axis(app.UIAxes,'xy'); 
            ylim(app.UIAxes,[app.lowerEditField.Value, app.upperEditField.Value]); 
            caxis(app.UIAxes,[app.LowdBEditField.Value,app.UpdBEditField.Value ]);
            xticks(app.UIAxes,app.startDay:1:app.endDay); datetick(app.UIAxes,'x','mm/dd','keepticks');
            xlim(app.UIAxes,[app.startDay (app.startDay + app.NumberofDaysEditField.Value)]);
            
%             %app.UIAxes(Toolbar.Visible)
%             
%             ax = app.UIAxes;
%             ax.Toolbar.Visible = 'on';
            
            app.chorusNum = 1; %set the first row of the table
            
            app.chorustable = table('Size',[1,7],'VariableTypes',{'string','string','double','double','double','double','string'},...
                 'VariableNames',{'site','deployment','chorusStartTime','chorusEndTime','chorusLowerFreq','chorusUpperFreq','comment'}); 
            
        end

        % Value changed function: lowerEditField
        function lowerEditFieldValueChanged(app, event)
            value = app.lowerEditField.Value;
            ylim(app.UIAxes,[value app.upperEditField.Value]); 
        end

        % Value changed function: upperEditField
        function upperEditFieldValueChanged(app, event)
            value = app.upperEditField.Value;
            ylim(app.UIAxes,[app.lowerEditField.Value value]); 
        end

        % Value changed function: UpdBEditField
        function UpdBEditFieldValueChanged(app, event)
            value = app.UpdBEditField.Value;
            caxis(app.UIAxes,[app.LowdBEditField.Value value]);
        end

        % Value changed function: LowdBEditField
        function LowdBEditFieldValueChanged(app, event)
            value = app.LowdBEditField.Value;
            caxis(app.UIAxes,[value, app.UpdBEditField.Value]);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            app.locDown = [app.temp(1,1) app.temp(1,2)]; % Gets the (x,y) coordinate
            %sprintf('Start Time: %5.3f upper F: %5.3f',app.locDown)
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            app.locUp = [app.temp(1,1) app.temp(1,2)]; % Gets the (x,y) coordinate
            %sprintf('Duration: %5.3f Delta-F%5.3f',app.locUp-app.locDown) 
            hold(app.UIAxes,'on')
            app.callbox = plot(app.UIAxes,[app.locDown(1),app.locUp(1),app.locUp(1),app.locDown(1),app.locDown(1)],[app.locDown(2),app.locDown(2),app.locUp(2),app.locUp(2),app.locDown(2)],'k','Linewidth',3);
       
       %after box is drawn, save information to table     
       app.chorustable(app.chorusNum,1)={app.currFile.site}; 
       app.chorustable(app.chorusNum,2)={app.currFile.dep}; 
       %app.chorustable(app.chorusNum,3)=num2cell(app.currFile.fstart); 
       app.chorustable(app.chorusNum,3)=num2cell(app.locDown(1)); 
       app.chorustable(app.chorusNum,4)=num2cell(app.locUp(1));
       app.chorustable(app.chorusNum,5)=num2cell(app.locUp(2)); 
       app.chorustable(app.chorusNum,6)=num2cell(app.locDown(2));  
       app.chorustable(app.chorusNum,7)={app.CommentEditField.Value}; %insert text from comment box
      
            
            app.chorusNum = app.chorusNum +1;


        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            app.xl = xlim(app.UIAxes);
            xlim(app.UIAxes,[app.xl(1) + 1 app.xl(2) + 1]); % could create a num value field to have # of days to increase?
            app.xl = [];
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.xl = xlim(app.UIAxes);
            xlim(app.UIAxes,[app.xl(1) - 1 app.xl(2) - 1]);
            app.xl=[];
        end

        % Value changed function: NumberofDaysEditField
        function NumberofDaysEditFieldValueChanged(app, event)
            value = app.NumberofDaysEditField.Value;
            app.xl = xlim(app.UIAxes);
            xlim(app.UIAxes, [app.xl(1) app.xl(1) + value]);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.chorustable
           fn=strcat('chorustable_',app.currFile.site,'_',app.currFile.dep); 
           fn=strrep(fn,'.','_'); 
           writetable(app.chorustable,strcat(app.pathoutEditField.Value,fn)); 
        end

        % Value changed function: CommentEditField
        function CommentEditFieldValueChanged(app, event)
            value = app.CommentEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Long-term Spectrogram')
            xlabel(app.UIAxes, 'Day (mm/yy)')
            ylabel(app.UIAxes, 'Frequency (Hz)')
            app.lowerEditFieldLabel = uilabel(app.UIFigure);
            app.lowerEditFieldLabel.Text = 'lower';
            app.lowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.lowerEditField.ValueChangedFcn = createCallbackFcn(app, @lowerEditFieldValueChanged, true);
            app.upperEditFieldLabel = uilabel(app.UIFigure);
            app.upperEditFieldLabel.Text = 'upper';
            app.upperEditField = uieditfield(app.UIFigure, 'numeric');
            app.upperEditField.ValueChangedFcn = createCallbackFcn(app, @upperEditFieldValueChanged, true);
            app.upperEditField.Value = 3000;
            app.YLimitsLabel = uilabel(app.UIFigure);
            app.YLimitsLabel.Text = 'Y Limits';
            app.UpdBEditFieldLabel = uilabel(app.UIFigure);
            app.UpdBEditFieldLabel.Text = 'Colormap Limits (dB)';
            app.UpdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdBEditField.ValueChangedFcn = createCallbackFcn(app, @UpdBEditFieldValueChanged, true);
            app.UpdBEditField.Value = 90;
            app.LowdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowdBEditField.ValueChangedFcn = createCallbackFcn(app, @LowdBEditFieldValueChanged, true);
            app.LowdBEditField.Value = 45;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.pathoutEditFieldLabel = uilabel(app.UIFigure);
            app.pathoutEditFieldLabel.Text = 'path out:';
            app.pathoutEditField = uieditfield(app.UIFigure, 'text');
            app.pathoutEditField.Value = '/Users/sbrown/Documents/MATLAB/call_classification/fknms_unsupervisedclassification/chorustables/';
            app.CommentEditField_2Label = uilabel(app.UIFigure);
            app.CommentEditField_2Label.Text = 'Comment';
            app.CommentEditField = uieditfield(app.UIFigure, 'text');
            app.CommentEditField.ValueChangedFcn = createCallbackFcn(app, @CommentEditFieldValueChanged, true);
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.NumberofDaysEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDaysEditFieldLabel.Text = 'Number of Days';
            app.NumberofDaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDaysEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofDaysEditFieldValueChanged, true);
            app.NumberofDaysEditField.Value = 3;
            app.NotematfileMUSTincludefstartfpoavgdepandsitevariablesLabel = uilabel(app.UIFigure);
            app.NotematfileMUSTincludefstartfpoavgdepandsitevariablesLabel.Text = 'Note: mat file MUST include fstart, f, poavg, dep, and site variables';
            app.EntertextBEFOREselectingchorusLabel = uilabel(app.UIFigure);
            app.EntertextBEFOREselectingchorusLabel.Text = 'Enter text BEFORE selecting chorus';
            app.lowerEditFieldLabel_2 = uilabel(app.UIFigure);
            app.lowerEditFieldLabel_2.Text = 'lower';
            app.upperEditFieldLabel_2 = uilabel(app.UIFigure);
            app.upperEditFieldLabel_2.Text = 'upper';
            app.ReadmeTextAreaLabel = uilabel(app.UIFigure);
            app.ReadmeTextAreaLabel.Text = 'Read me!';
            app.ReadmeTextArea = uitextarea(app.UIFigure);
            app.ReadmeTextArea.Value = {'How to use the chorus picker:'; ''; '1. Load data (pop up window to select .mat file). This will create a long term spectrogram of your data.'; ''; '2. Draw boxes around chorus features. Use forward button to pan figure 1 day forward. Use back button to go back one day.'; ''; '3. Use Save button to save a table of all picked choruses.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chorusPicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end